classdef invFilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BealltsokMenu            matlab.ui.container.Menu
        SliderfelbontasMenu      matlab.ui.container.Menu
        KisfelbontasMenu         matlab.ui.container.Menu
        KozepesfelbontasMenu     matlab.ui.container.Menu
        NagyfelbontasMenu        matlab.ui.container.Menu
        ModostandoListBox        matlab.ui.control.ListBox
        MdostandszrListBoxLabel  matlab.ui.control.Label
        CszrtClass2Lamp          matlab.ui.control.Lamp
        CszrtClass2LampLabel     matlab.ui.control.Label
        CszrtClass1Lamp          matlab.ui.control.Lamp
        CszrtClass1LampLabel     matlab.ui.control.Label
        AszrtClass2Lamp          matlab.ui.control.Lamp
        AszrtClass2LampLabel     matlab.ui.control.Label
        AszrtClass1Lamp          matlab.ui.control.Lamp
        AszrtClass1LampLabel     matlab.ui.control.Label
        FilterButtonGroup        matlab.ui.container.ButtonGroup
        HPFButton                matlab.ui.control.ToggleButton
        LPFButton                matlab.ui.control.ToggleButton
        ParamButton              matlab.ui.control.ToggleButton
        restartButton            matlab.ui.control.Button
        Axes3Tools               matlab.ui.container.ButtonGroup
        Zoom3Button              matlab.ui.control.ToggleButton
        Pan3Button               matlab.ui.control.ToggleButton
        Axes2Tools               matlab.ui.container.ButtonGroup
        Zoom2Button              matlab.ui.control.ToggleButton
        Pan2Button               matlab.ui.control.ToggleButton
        Axes1Tools               matlab.ui.container.ButtonGroup
        Zoom1Button              matlab.ui.control.ToggleButton
        Pan1Button               matlab.ui.control.ToggleButton
        finishButton             matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        bwSlider                 matlab.ui.control.Slider
        fcSlider                 matlab.ui.control.Slider
        gainSlider               matlab.ui.control.Slider
        bwField                  matlab.ui.control.NumericEditField
        bwoctaveLabel            matlab.ui.control.Label
        fcField                  matlab.ui.control.NumericEditField
        fcHzLabel                matlab.ui.control.Label
        gainField                matlab.ui.control.NumericEditField
        GaindBLabel              matlab.ui.control.Label
        ListBox                  matlab.ui.control.ListBox
        HasznltszrkListBoxLabel  matlab.ui.control.Label
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        OriginalFilters % Filters from the algorithm
        SelectedFilter
        ModFilters
        Params
        FinalFilters
        OriginalMic
        GainValues
        FcValues
        BwValues
        GainLimit
        FcLimit
        BwLimit
        TolIndex
        SliderRes
        RunCount
        InputFilePath
        DBFilePath
        FinalA1Filters
        FinalC1Filters
        FinalA2Filters
        FinalC2Filters
        PhoneId
        IMEI
        Model
        MarketName
        FullScaleSPL
        MinFreq
        MaxFreq        
    end

    methods (Access = private)
    
        function plotModFilter(app,changingValue,paramNum)
            fs = 44100;
            f_interp_plot = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.PLOT_FLINES);
            i = app.SelectedFilter;
            if (app.ModFilters(i,6) == 0) % parametric filter
                switch(paramNum)
                    case 1
                        [He,~] = parametricEQ(changingValue,app.ModFilters(i,2),app.ModFilters(i,3),fs,f_interp_plot);
                    case 2
                        [He,~] = parametricEQ(app.ModFilters(i,1),changingValue,app.ModFilters(i,3),fs,f_interp_plot);
                    case 3
                        [He,~] = parametricEQ(app.ModFilters(i,1),app.ModFilters(i,2),changingValue,fs,f_interp_plot);
                end
            else % butterworth filter
                if (paramNum == 2)
                    if(app.ModFilters(i,6) == 1)
                        [b,a] = butter(2,changingValue/(fs/2),'high');
                    else
                        [b,a] = butter(2,changingValue/(fs/2),'low');
                    end
                end
                [He,~] = freqz(b,a,f_interp_plot,fs);
                He = 20*log10(abs(He));
            end
            semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
            hold(app.UIAxes3,'on')
            grid(app.UIAxes3,'on')
            plotModTransfer(app)
            hold(app.UIAxes3,'off')
        end
        
        function plotModTransfer(app)
            fs = 44100;
            f_interp_plot = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.PLOT_FLINES);
            f_interp = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.COMP_FLINES);
            H_mic = interp1(f_interp_plot,app.OriginalMic,f_interp,'pchip');
            He = H_mic;
            for i = 1:size(app.ModFilters,1)
                if (app.ModFilters(i,7) == 1)
                    if (app.ModFilters(i,6) == 0)
                        [H,~] = parametricEQ(app.ModFilters(i,1),app.ModFilters(i,2),app.ModFilters(i,3),fs,f_interp_plot);
                    else % butterworth filter
                        if(app.ModFilters(i,6) == 1)
                            [b,a] = butter(2,app.ModFilters(i,2)/(fs/2),'high');
                        else
                            [b,a] = butter(2,app.ModFilters(i,2)/(fs/2),'low');
                        end
                        [H,~] = freqz(b,a,f_interp_plot,fs);
                        H = 20*log10(abs(H));
                    end
                    He = He + H;
                end
            end
            semilogx(app.UIAxes3,f_interp_plot,He)
            plotTolerance(app)
            
        end
        
        function enableModFields(app)
            app.gainField.Enable = 'on';
            app.fcField.Enable = 'on';
            app.bwField.Enable = 'on';
            app.gainSlider.Enable = 'on';
            app.fcSlider.Enable = 'on';
            app.bwSlider.Enable = 'on';
            app.ParamButton.Enable = 'on';
            app.LPFButton.Enable = 'on';
            app.HPFButton.Enable = 'on';
        end
        
        function plotTolerance(app) % tol_index = 1 Class1; tol_index = 2 Class2
             load('tolerance.mat','tolerance');
             f_interp_plot = logspace(log10(app.Params.START_FREQ),log10(app.Params.END_FREQ),app.Params.PLOT_FLINES);
             tol_interp_plot = (interp1(tolerance(:,1), tolerance(:,app.TolIndex*2:app.TolIndex*2+1) , f_interp_plot, 'pchip'))';
             semilogx(app.UIAxes3,f_interp_plot, tol_interp_plot(1:2,:),'r--')
             
        end
        
        function calcSliderValues(app)
            app.GainValues = linspace(app.GainLimit(1),app.GainLimit(2),app.SliderRes);
            app.FcValues = logspace(log10(app.FcLimit(1)),log10(app.FcLimit(2)),app.SliderRes);
            app.BwValues = linspace(app.BwLimit(1),app.BwLimit(2),app.SliderRes);
            app.gainSlider.Limits = [0 app.SliderRes];
            app.fcSlider.Limits = [0 app.SliderRes];
            app.bwSlider.Limits = [0 app.SliderRes];
            if (app.SelectedFilter ~= 0)
                i = app.SelectedFilter;
                app.gainSlider.Value = find((app.GainValues(1:end-1) <=  app.ModFilters(i,1)) & (app.GainValues(2:end) >  app.ModFilters(i,1)),1);
                app.fcSlider.Value = find((app.FcValues(1:end-1) <= app.ModFilters(i,2)) & (app.FcValues(2:end) > app.ModFilters(i,2)),1);
                app.bwSlider.Value = find((app.BwValues(1:end-1) <= app.ModFilters(i,3))&(app.BwValues(2:end) > app.ModFilters(i,3)),1);
            end
        end
        
        function configure(app)

%             app.ModostandoListBox.Value = app.ModostandoListBox.Items{1};
%             app.SelectedFilter = 1;
            
            
            if (app.RunCount == 1)
                if(isempty(app.InputFilePath))
                    [file,path] = uigetfile('*.mat');
                    app.InputFilePath = strcat(path,file);
                end
                title = strsplit(app.InputFilePath,'\');
                app.UIFigure.Name = strcat('Kompenzálás: ',title{end});
            end
            
            fontSizeText = '\fontsize{13} ';  
%                 'Hibaszámítási mód (1 = abszolútértékek összege; 2 = négyzetek összege; 3 = abszolútértékek összege + csak a toleranciasávon kívül eső részek figyelembevétele; 4 = négyzetek összege + csak a toleranciasávon kívül eső részek figyelembevétele)'};
            prompt = {
                'Vizsgálati freki tartomány alsó határa:', 
                'Vizsgálati freki tartomány felső határa:', 
                'Számítási felbontás:', 
                'Megjelenítési felbontás', 
                'Parametrikus szűrő paramétereinek becséli módja:',
                'Ha a becslési mód = 1, összes paraméter ismétlése:',
                'Ha a becslési mód = 1, egy paraméter ismétlése:',
                'Ha a becslési mód = 2, összes ismétlés:',
                sprintf('%s\n%s\n%s\n%s\n%s\n','Hibaszámítási mód:',' 1 = abszolútértékek összege',' 2 = négyzetek összege',' 3 = abszolútértékek összege + csak a toleranciasávon kívül eső részek figyelembevétele',' 4 = négyzetek összege + csak a toleranciasávon kívül eső részek figyelembevétele')};
            for i=1:length(prompt)
                prompt{i} = [fontSizeText prompt{i}]; 
            end
            title = 'Input';
            dims = [1 80];
            if(app.RunCount == 1)
                definput = { num2str(app.MinFreq),num2str(app.MaxFreq),'1000','1000','1','20','30','1000','4'};
            else
                app.Params = load('config.mat');
                definput = {num2str(app.Params.START_FREQ), num2str(app.Params.END_FREQ), num2str(app.Params.COMP_FLINES), num2str(app.Params.PLOT_FLINES), num2str(app.Params.EST_MODE), num2str(app.Params.OUTER_LOOP), num2str(app.Params.INNER_LOOP), num2str(app.Params.CYCLES), num2str(app.Params.ERROR_SEL)};    
            end
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,title,dims,definput, opts);
            START_FREQ = str2double(answer{1}); % vizsgálati freki tartomány alja
            END_FREQ = str2double(answer{2}); % vizsgálati freki tartomány teteje
            COMP_FLINES = str2double(answer{3}); % az átviteli fgv számításakor használt felbontás
            PLOT_FLINES = str2double(answer{4}); % a megjelenítéshez használt felbontás
            
%             if (app.RunCount == 1 && app.RunCount == 3)
%                 A_FILT = 1; % compensation with A-filter
%                 C_FILT = 0; % compensation with C-filter
%             else
%                 A_FILT = 0; % compensation with A-filter
%                 C_FILT = 1; % compensation with C-filter
%             end
            
            NO_FILT = 0; % compensation to flat frequency response
            
            HPF_BUTTER = 1; % use highpass butterworth filter 
            LPF_BUTTER = 1; % use lowpass butterworth filter
            
%             TOLERANCE = 1 - Class1, 2 - Class2, 3 - Class1 and Class2
%             A_FILT = 1 ; or = 0   compensation with A-filter: YES or NO
%             C_FILT = 1 ; or = 0   compensation with C-filter: YES or NO

            switch(app.RunCount)
                case 1
                    TOLERANCE = 1; A_FILT = 1; C_FILT = 0;
                case 2
                    TOLERANCE = 2; A_FILT = 1; C_FILT = 0;
                case 3 
                    TOLERANCE = 1; A_FILT = 0; C_FILT = 1;
                case 4
                    TOLERANCE = 2; A_FILT = 0; C_FILT = 1;
            end
            
            PAUSE = 0; % 0=run without pausing, 1 = run with pausing
            
            fileNames = {app.InputFilePath};
           
%             fileNames = {
%               'CutOf_iPhone_5S_MFRecorder_2017-04-27 17-08-07_SweepOnly_h_bSpectr'
%             % 'CutOf_Samsung Galaxy Ace 20170427_173930_SweepOnly_h_bSpectr'
%             %  'CutOf_Samsung Galaxy Alpha - 20170427_183001_SweepOnly_h_bSpectr'
%             %'CutOf_Samsung Galaxy Mini 2 - 20170427_174623_SweepOnly_h_bSpectr'
%             %'RecordedAudio_noProcess_SweepOnly_h_bSpectr_'
%             };
            
            %% ParamaetricEQEst
            
            EST_MODE = str2double(answer{5}); % 1 = fc,gain,bw; 2 = random parameter select 
            
            % IF EST_MODE = 1
            OUTER_LOOP = str2double(answer{6});
            INNER_LOOP = str2double(answer{7});
            
            % IF EST_MODE = 2
            CYCLES = str2double(answer{8});
            
            %% ToleranceOptim
            
            LIMIT = 0.1; % effective range limit
            MAX_CYCLE = 15; % maxumim number of cycles 
            EQUAL_TOLERANCE = 0.05; % stop if the difference between distance from lower and upper band is under this value
            
            %% ErrorCalc
            ERROR_SEL = str2double(answer{9}); % sum of abs (ERROR_SEL = 1)
                          % sum of squares (ERROR_SEL = 2)
                          % sum of abs where H_mic is outside the tolerance band (ERROR_SEL = 3)
                          % sum of squares where H_mic is outside the tolerance band (ERROR_SEL = 4)
            
            
            %% SAVE variables
            
            save('config.mat','START_FREQ','END_FREQ', 'COMP_FLINES', 'PLOT_FLINES', 'A_FILT', 'C_FILT', 'NO_FILT','HPF_BUTTER','LPF_BUTTER', 'TOLERANCE','PAUSE','fileNames','EST_MODE','OUTER_LOOP','INNER_LOOP','CYCLES','LIMIT','MAX_CYCLE','EQUAL_TOLERANCE','ERROR_SEL')
        end
        
        function calcFilters(app)
            progressDialog = uiprogressdlg(app.UIFigure,'Title','Szűrők számítása','Indeterminate','on');
            [filters,H_mic] = invFilterDesign(app.UIAxes,app.UIAxes2);
            hold(app.UIAxes,'off')
            hold(app.UIAxes2,'off')
            close(progressDialog)
            %load('filters.mat', 'filters','H_mic');
            app.OriginalMic = H_mic;
            app.OriginalFilters = filters;
            app.ModFilters = [app.OriginalFilters ones(size(app.OriginalFilters,1),1)];
            items = cell(1,size(app.OriginalFilters,1));
            for i=1:size(app.OriginalFilters,1)
                items{i} = ['Szűrő ' num2str(i,'%d')];  
            end
            app.ListBox.Items = items;
            app.ModostandoListBox.Items = items;
            app.ModostandoListBox.Value = items{1};
            
            app.Params = load('config.mat');
            app.ListBox.Value = items;
            app.GainLimit = [-40 40];
            app.FcLimit = [1 22000];
            app.BwLimit = [0 20];
            app.gainField.Limits = app.GainLimit;
            app.fcField.Limits = [app.FcLimit(1) app.FcLimit(2)+1];
            app.bwField.Limits = app.BwLimit;
            app.SelectedFilter = 1;
            NagyfelbontasMenuSelected(app, []);
            e = 0;
            ModostandoListBoxValueChanged(app,e);
        end
        
        
        function createJsonOutput(app)
            filters = app.FinalA1Filters;
            for j = 1:4
                p_i = 0;
                h_i = 0;
                l_i = 0;
                param = [];
                hpf = [];
                lpf = [];
                for i = 1 : size(filters,1)
                    switch(filters(i,6))
                        case 0 % parametric
                            p_i = p_i + 1;
                            param(p_i).gain = filters(i,1);
                            param(p_i).fc = filters(i,2);
                            param(p_i).bandwidth = filters(i,3);
                        case 1 % hpf
                            h_i = h_i + 1;
                            hpf(h_i).fc = filters(i,2);
                            
                        case 2 % lpf
                            l_i = l_i + 1;
                            lpf(l_i).fc = filters(i,2);
                    end
                    
                end
                filt.HPF = hpf;
                filt.LPF = lpf;
                filt.Parametric = param;
                switch (j)
                    case 1
                        class1.A_weighted=filt;
                        filters = app.FinalC1Filters;
                    case 2
                        class1.C_weighted=filt;
                        filters = app.FinalA2Filters;
                    case 3
                         class2.A_weighted=filt;
                         filters = app.FinalC2Filters;
                    case 4
                        class2.C_weighted=filt;
                end
            end
            if(isempty(app.DBFilePath))
                [file,path] = uigetfile('*.json');
                app.DBFilePath = strcat(path,file);
            end
            text = fileread(app.DBFilePath);
            value = jsondecode(text);
          
            phones.phone_id = app.PhoneId;
            phones.imei = app.IMEI;
            phones.model = app.Model;
            phones.market_name = app.MarketName;
            phones.full_scale_spl = app.FullScaleSPL;
            phones.Class1 = class1;
            phones.Class2 = class2;
            phones = [phones; value.Phones];
%             index = size(phones,1)+1;
%             phones(index).Class1 = class1;
%             phones(index).Class2 = class2;
%             phones(index).phone_id = app.PhoneId;
%             phones(index).imei = app.IMEI;
%             phones(index).model = app.Model;
%             phones(index).market_name = app.MarketName;
            database.Phones = phones;
            json_txt = jsonencode(database);
            fid = fopen(app.DBFilePath,'wt');
            fprintf(fid, json_txt);
            fclose(fid);

        end
        
        function initVariables(app,initarg)
            
            app.MinFreq = 50;
            app.MinFreq = 16000;
            
            if ~isstruct(initarg)
                return
            end
            
            if(~isempty(initarg.inputfile))
                app.InputFilePath = initarg.inputfile;
            end
            if(~isempty(initarg.dbfile))
                app.DBFilePath = initarg.dbfile;
            end
            if(~isempty(initarg.phoneId))
                app.PhoneId = initarg.phoneId;
            end
            if(~isempty(initarg.imei))
                app.IMEI = initarg.imei;
            end
            if(~isempty(initarg.model))
                app.Model = initarg.model;
            end
            if(~isempty(initarg.marketName))
                app.MarketName = initarg.marketName;
            end
            if(~isempty(initarg.fullScaleSPL))
                app.FullScaleSPL = initarg.fullScaleSPL;
            end
            if(~isempty(initarg.minFreq))
                app.MinFreq = initarg.minFreq;
            end
            if(~isempty(initarg.maxFreq))
                app.MaxFreq = initarg.maxFreq;
            end
        end
    end

    
    methods (Access = public)
        
        function setPhoneInfo(app,phoneId,imei,model,marketName)
            app.PhoneId = phoneId;
            app.IMEI = imei;
            app.Model = model;
            app.MarketName = marketName;
        end
    
        function setInputFile(app,filepath)
            app.InputFilePath = filepath;
        end
    
        function setDBFile(app,filepath)
            app.DBFilePath = filepath;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initarg)
            app.RunCount = 1;
            initVariables(app,initarg)
            configure(app)
                     
            app.TolIndex = 1;
            calcFilters(app)

        end

        % Value changed function: gainField
        function gainFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,1) = changingValue;
            plotModFilter(app,changingValue,1);
            app.gainSlider.Value = find((app.GainValues(1:end-1) <= changingValue) & (app.GainValues(2:end) > changingValue),1);
        end

        % Value changed function: fcField
        function fcFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,2) = changingValue;
            plotModFilter(app,changingValue,2);
            app.fcSlider.Value = find((app.FcValues(1:end-1) <= changingValue) & (app.FcValues(2:end) > changingValue),1);
        end

        % Value changed function: bwField
        function bwFieldValueChanged(app, event)
            changingValue = event.Value;
            app.ModFilters(app.SelectedFilter,3) = changingValue;
            plotModFilter(app,changingValue,3);
            app.bwSlider.Value = find((app.BwValues(1:end-1) <= changingValue)&(app.BwValues(2:end) > changingValue),1);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.ModostandoListBox.Items = value;
            for i = 1:size(app.ListBox.Items,2)
                for j = 1:size(value,2)
                    if (isequal(value(j), app.ListBox.Items(i)))
                        app.ModFilters(i,7) = 1;
                        break;
                    else
                        app.ModFilters(i,7) = 0;
                    end
                end
            end
            ModostandoListBoxValueChanged(app,event);
        end

        % Value changing function: gainSlider
        function gainSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.GainValues(changingValue);
            %gainFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,1) = app.GainValues(changingValue);
            plotModFilter(app,app.GainValues(changingValue),1);
            app.gainField.Value = ev.Value;
        end

        % Value changing function: fcSlider
        function fcSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.FcValues(changingValue);
            %fcFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,2) = app.FcValues(changingValue);
            plotModFilter(app, app.FcValues(changingValue),2);
            app.fcField.Value = ev.Value;
            
        end

        % Value changing function: bwSlider
        function bwSliderValueChanging(app, event)
            changingValue = round(event.Value);
            if(changingValue == 0)
                changingValue = 1;
            end
          
            ev.Value = app.BwValues(changingValue);
            %bwFieldValueChanged(app, ev);
            app.ModFilters(app.SelectedFilter,3) = app.BwValues(changingValue);
            plotModFilter(app,app.BwValues(changingValue),3);
            app.bwField.Value = ev.Value;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.ModFilters = [app.OriginalFilters ones(size(app.OriginalFilters,1),1)];
            items = cell(1,size(app.OriginalFilters,1));
            for i=1:size(app.OriginalFilters,1)
                items{i} = ['Szűrő ' num2str(i,'%d')];  
            end
            app.ListBox.Items = items;
            app.ListBox.Value = items;
            app.ModostandoListBox.Items = items;
            plotModFilter(app,app.ModFilters(app.SelectedFilter ,2),2);
            e=0;
            ModostandoListBoxValueChanged(app,e);
        end

        % Button pushed function: finishButton
        function finishButtonPushed(app, event)
            switch (app.RunCount)
                case 1
                    app.AszrtClass1Lamp.Color = 'green';
                    app.AszrtClass1LampLabel.FontWeight = 'normal';
                    app.AszrtClass2Lamp.Color = 'yellow';
                    app.AszrtClass2LampLabel.FontWeight = 'bold';
                    app.FinalA1Filters = app.ModFilters(app.ModFilters(:,7) == 1,:);
                    %app.finishButton.Text = 'A-szűrt Class 2 kész';
                    app.RunCount = app.RunCount + 1;
                    configure(app)
                    app.TolIndex = 2;
                    calcFilters(app);
                case 2
                    app.AszrtClass2Lamp.Color = 'green';
                    app.AszrtClass2LampLabel.FontWeight = 'normal';
                    app.CszrtClass1Lamp.Color = 'yellow';
                    app.CszrtClass1LampLabel.FontWeight = 'bold';
                    app.FinalA2Filters = app.ModFilters(app.ModFilters(:,7) == 1,:);
                    %app.finishButton.Text = 'C-szűrt Class 1 kész';
                    app.RunCount = app.RunCount + 1;
                    configure(app)
                    app.TolIndex = 1;
                    calcFilters(app);
                case 3
                    app.CszrtClass1Lamp.Color = 'green';
                    app.CszrtClass1LampLabel.FontWeight = 'normal';
                    app.CszrtClass2Lamp.Color = 'yellow';
                    app.CszrtClass2LampLabel.FontWeight = 'bold';
                    app.FinalC1Filters = app.ModFilters(app.ModFilters(:,7) == 1,:);
                    app.RunCount = app.RunCount + 1;
                    configure(app)
                    app.TolIndex = 2;
                    calcFilters(app);   
                    app.finishButton.Text = 'Kész';
                case 4
                    app.CszrtClass2Lamp.Color = 'green';
                    app.CszrtClass2LampLabel.FontWeight = 'normal';
                    app.FinalC2Filters = app.ModFilters(app.ModFilters(:,7) == 1,:);
                    createJsonOutput(app);
                    msgbox({'A szűrőtervezés befejeződött!';'Frissült az adatbázis!'});
                    
            end
           
            
        end

        % Menu selected function: NagyfelbontasMenu
        function NagyfelbontasMenuSelected(app, event)
            app.KisfelbontasMenu.Checked = 'off';
            app.KozepesfelbontasMenu.Checked = 'off';
            app.NagyfelbontasMenu.Checked = 'on';
            app.SliderRes = 10000;
            calcSliderValues(app)
        end

        % Menu selected function: KozepesfelbontasMenu
        function KozepesfelbontasMenuSelected(app, event)
            app.KisfelbontasMenu.Checked = 'off';
            app.KozepesfelbontasMenu.Checked = 'on';
            app.NagyfelbontasMenu.Checked = 'off';
            app.SliderRes = 1000;
            calcSliderValues(app)
        end

        % Menu selected function: KisfelbontasMenu
        function KisfelbontasMenuSelected(app, event)
            app.KisfelbontasMenu.Checked = 'on';
            app.KozepesfelbontasMenu.Checked = 'off';
            app.NagyfelbontasMenu.Checked = 'off';
            app.SliderRes = 100;
            calcSliderValues(app)
        end

        % Selection changed function: Axes1Tools
        function Axes1ToolsSelectionChanged(app, event)
            selectedButton = app.Axes1Tools.SelectedObject;
            if (selectedButton == app.Pan1Button)
                pan(app.UIAxes,'on');
                zoom(app.UIAxes,'off');
            elseif (selectedButton == app.Zoom1Button)
                pan(app.UIAxes,'off');
                zoom(app.UIAxes,'on');
            end
        end

        % Selection changed function: Axes2Tools
        function Axes2ToolsSelectionChanged(app, event)
            selectedButton = app.Axes2Tools.SelectedObject;
            if (selectedButton == app.Pan2Button)
                pan(app.UIAxes2,'on');
                zoom(app.UIAxes2,'off');
            elseif (selectedButton == app.Zoom2Button)
                pan(app.UIAxes2,'off');
                zoom(app.UIAxes2,'on');
            end
            
        end

        % Selection changed function: Axes3Tools
        function Axes3ToolsSelectionChanged(app, event)
            selectedButton = app.Axes3Tools.SelectedObject;
            if (selectedButton == app.Pan3Button)
                pan(app.UIAxes3,'on');
                zoom(app.UIAxes3,'off');
            elseif (selectedButton == app.Zoom3Button)
                pan(app.UIAxes3,'off');
                zoom(app.UIAxes3,'on');
            end
        end

        % Button pushed function: restartButton
        function restartButtonPushed(app, event)
%             app.ModostandoListBox.Value = app.ModostandoListBox.Items{1};
%             app.SelectedFilter = 1;
            calcFilters(app);
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
           
            switch (selectedButton)
                case app.ParamButton
                    app.ModFilters(app.SelectedFilter,6) = 0;
                    app.gainSlider.Enable = 'on';
                    app.bwSlider.Enable = 'on';
                    app.gainField.Enable = 'on';
                    app.bwField.Enable = 'on';
                case app.LPFButton
                    app.ModFilters(app.SelectedFilter,6) = 2;
                    app.gainSlider.Enable = 'off';
                    app.bwSlider.Enable = 'off';
                    app.gainField.Enable = 'off';
                    app.bwField.Enable = 'off';
                case app.HPFButton
                    app.ModFilters(app.SelectedFilter,6) = 1;
                    app.gainSlider.Enable = 'off';
                    app.bwSlider.Enable = 'off';
                    app.gainField.Enable = 'off';
                    app.bwField.Enable = 'off';
            end
            plotModFilter(app,app.ModFilters(app.SelectedFilter,2),2);
        end

        % Value changed function: ModostandoListBox
        function ModostandoListBoxValueChanged(app, event)
            value = app.ModostandoListBox.Value;
            enableModFields(app);
            for i = 1:size(app.ModFilters,1)
                if (isequal(value,app.ListBox.Items{i}))
                    app.SelectedFilter = i;                                    
                    xlim(app.UIAxes3,[app.Params.START_FREQ app.Params.END_FREQ])
                    plotModFilter(app,app.ModFilters(i,2),2);
                    switch(app.ModFilters(i,6))
                        case 0
                          app.FilterButtonGroup.SelectedObject = app.ParamButton;
                        case 1
                          app.FilterButtonGroup.SelectedObject = app.HPFButton;
                        case 2
                          app.FilterButtonGroup.SelectedObject = app.LPFButton;
                    end
                    app.gainField.Value = app.ModFilters(i,1);
                    app.fcField.Value = app.ModFilters(i,2);
                    app.bwField.Value = app.ModFilters(i,3);
                    %slider init
                    app.gainSlider.Value = find((app.GainValues(1:end-1) <=  app.ModFilters(i,1)) & (app.GainValues(2:end) >  app.ModFilters(i,1)),1);
                    app.fcSlider.Value = find((app.FcValues(1:end-1) <= app.ModFilters(i,2)) & (app.FcValues(2:end) > app.ModFilters(i,2)),1);
                    app.bwSlider.Value = find((app.BwValues(1:end-1) <= app.ModFilters(i,3))&(app.BwValues(2:end) > app.ModFilters(i,3)),1);
                    if(app.ModFilters(i,6) ~= 0) % ha lpf vagy hpf a gain és bw nem állítható
                        app.gainField.Enable = 'off';
                        app.bwField.Enable = 'off';
                        app.gainSlider.Enable = 'off';
                        app.bwSlider.Enable = 'off';
                    else
                        app.gainField.Enable = 'on';
                        app.bwField.Enable = 'on';
                        app.gainSlider.Enable = 'on';
                        app.bwSlider.Enable = 'on';
                    end
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Kompenzálás: ';
            app.BealltsokMenu = uimenu(app.UIFigure);
            app.BealltsokMenu.Text = 'Beállítások';
            app.SliderfelbontasMenu = uimenu(app.BealltsokMenu);
            app.SliderfelbontasMenu.Text = 'Slider felbontás';
            app.KisfelbontasMenu = uimenu(app.SliderfelbontasMenu);
            app.KisfelbontasMenu.MenuSelectedFcn = createCallbackFcn(app, @KisfelbontasMenuSelected, true);
            app.KisfelbontasMenu.Checked = 'on';
            app.KisfelbontasMenu.Text = 'Kis felbontás';
            app.KozepesfelbontasMenu = uimenu(app.SliderfelbontasMenu);
            app.KozepesfelbontasMenu.MenuSelectedFcn = createCallbackFcn(app, @KozepesfelbontasMenuSelected, true);
            app.KozepesfelbontasMenu.Checked = 'on';
            app.KozepesfelbontasMenu.Text = 'Közepes felbontás';
            app.NagyfelbontasMenu = uimenu(app.SliderfelbontasMenu);
            app.NagyfelbontasMenu.MenuSelectedFcn = createCallbackFcn(app, @NagyfelbontasMenuSelected, true);
            app.NagyfelbontasMenu.Checked = 'on';
            app.NagyfelbontasMenu.Text = 'Nagy felbontás';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Átvitel változása')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Szűrők')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Végleges átvitel')
            xlabel(app.UIAxes3, 'Frequency [Hz]')
            ylabel(app.UIAxes3, 'Magnitude [dB]')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.HasznltszrkListBoxLabel = uilabel(app.UIFigure);
            app.HasznltszrkListBoxLabel.Text = 'Használt szűrők';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.GaindBLabel = uilabel(app.UIFigure);
            app.GaindBLabel.Text = 'Gain [dB]';
            app.gainField = uieditfield(app.UIFigure, 'numeric');
            app.gainField.ValueChangedFcn = createCallbackFcn(app, @gainFieldValueChanged, true);
            app.gainField.Enable = 'off';
            app.fcHzLabel = uilabel(app.UIFigure);
            app.fcHzLabel.Text = 'fc [Hz]';
            app.fcField = uieditfield(app.UIFigure, 'numeric');
            app.fcField.ValueChangedFcn = createCallbackFcn(app, @fcFieldValueChanged, true);
            app.fcField.Enable = 'off';
            app.bwoctaveLabel = uilabel(app.UIFigure);
            app.bwoctaveLabel.Text = 'bw [octave]';
            app.bwField = uieditfield(app.UIFigure, 'numeric');
            app.bwField.ValueChangedFcn = createCallbackFcn(app, @bwFieldValueChanged, true);
            app.bwField.Enable = 'off';
            app.gainSlider = uislider(app.UIFigure);
            app.gainSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.gainSlider.ValueChangingFcn = createCallbackFcn(app, @gainSliderValueChanging, true);
            app.gainSlider.Enable = 'off';
            app.fcSlider = uislider(app.UIFigure);
            app.fcSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.fcSlider.ValueChangingFcn = createCallbackFcn(app, @fcSliderValueChanging, true);
            app.fcSlider.Enable = 'off';
            app.bwSlider = uislider(app.UIFigure);
            app.bwSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.bwSlider.ValueChangingFcn = createCallbackFcn(app, @bwSliderValueChanging, true);
            app.bwSlider.Enable = 'off';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'Visszaállítás';
            app.finishButton = uibutton(app.UIFigure, 'push');
            app.finishButton.ButtonPushedFcn = createCallbackFcn(app, @finishButtonPushed, true);
            app.finishButton.Text = 'Tovább';
            app.Axes1Tools = uibuttongroup(app.UIFigure);
            app.Axes1Tools.SelectionChangedFcn = createCallbackFcn(app, @Axes1ToolsSelectionChanged, true);
            app.Pan1Button = uitogglebutton(app.Axes1Tools);
            app.Pan1Button.Text = 'Pan';
            app.Pan1Button.Value = true;
            app.Zoom1Button = uitogglebutton(app.Axes1Tools);
            app.Zoom1Button.Text = 'Zoom';
            app.Axes2Tools = uibuttongroup(app.UIFigure);
            app.Axes2Tools.SelectionChangedFcn = createCallbackFcn(app, @Axes2ToolsSelectionChanged, true);
            app.Pan2Button = uitogglebutton(app.Axes2Tools);
            app.Pan2Button.Text = 'Pan';
            app.Pan2Button.Value = true;
            app.Zoom2Button = uitogglebutton(app.Axes2Tools);
            app.Zoom2Button.Text = 'Zoom';
            app.Axes3Tools = uibuttongroup(app.UIFigure);
            app.Axes3Tools.SelectionChangedFcn = createCallbackFcn(app, @Axes3ToolsSelectionChanged, true);
            app.Pan3Button = uitogglebutton(app.Axes3Tools);
            app.Pan3Button.Text = 'Pan';
            app.Pan3Button.Value = true;
            app.Zoom3Button = uitogglebutton(app.Axes3Tools);
            app.Zoom3Button.Text = 'Zoom';
            app.restartButton = uibutton(app.UIFigure, 'push');
            app.restartButton.ButtonPushedFcn = createCallbackFcn(app, @restartButtonPushed, true);
            app.restartButton.Text = 'Újraszámítás';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.ParamButton = uitogglebutton(app.FilterButtonGroup);
            app.ParamButton.Enable = 'off';
            app.ParamButton.Text = 'Parametrikus';
            app.ParamButton.Value = true;
            app.LPFButton = uitogglebutton(app.FilterButtonGroup);
            app.LPFButton.Enable = 'off';
            app.LPFButton.Text = 'LPF';
            app.HPFButton = uitogglebutton(app.FilterButtonGroup);
            app.HPFButton.Enable = 'off';
            app.HPFButton.Text = 'HPF';
            app.AszrtClass1LampLabel = uilabel(app.UIFigure);
            app.AszrtClass1LampLabel.Text = 'A-szűrt Class 1';
            app.AszrtClass1Lamp = uilamp(app.UIFigure);
            app.AszrtClass2LampLabel = uilabel(app.UIFigure);
            app.AszrtClass2LampLabel.Text = 'A-szűrt Class 2';
            app.AszrtClass2Lamp = uilamp(app.UIFigure);
            app.CszrtClass1LampLabel = uilabel(app.UIFigure);
            app.CszrtClass1LampLabel.Text = 'C-szűrt Class 1';
            app.CszrtClass1Lamp = uilamp(app.UIFigure);
            app.CszrtClass2LampLabel = uilabel(app.UIFigure);
            app.CszrtClass2LampLabel.Text = 'C-szűrt Class 2';
            app.CszrtClass2Lamp = uilamp(app.UIFigure);
            app.MdostandszrListBoxLabel = uilabel(app.UIFigure);
            app.MdostandszrListBoxLabel.Text = 'Módosítandó szűrő';
            app.ModostandoListBox = uilistbox(app.UIFigure);
            app.ModostandoListBox.ValueChangedFcn = createCallbackFcn(app, @ModostandoListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = invFilterDesignApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end